classdef robot_control_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChooseStartandTargetforrobotpentaskLabel  matlab.ui.control.Label
        ChoosecoordinatesforrobotpentargetLabel  matlab.ui.control.Label
        ZpositionEditField          matlab.ui.control.NumericEditField
        ZpositionEditFieldLabel     matlab.ui.control.Label
        YpositionEditField          matlab.ui.control.NumericEditField
        YpositionEditFieldLabel     matlab.ui.control.Label
        XpositionEditField          matlab.ui.control.NumericEditField
        XpositionEditFieldLabel     matlab.ui.control.Label
        GoButton_2                  matlab.ui.control.Button
        TargetDropDown              matlab.ui.control.DropDown
        TargetDropDownLabel         matlab.ui.control.Label
        StartDropDown               matlab.ui.control.DropDown
        StartDropDownLabel          matlab.ui.control.Label
        EditField3                  matlab.ui.control.NumericEditField
        EditField2                  matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        ShoulderLabel_2             matlab.ui.control.Label
        baza_slider                 matlab.ui.control.Slider
        srednjizglob_slider         matlab.ui.control.Slider
        zadnjizglob_slider          matlab.ui.control.Slider
        WristLabel                  matlab.ui.control.Label
        ElbowLabel_2                matlab.ui.control.Label
        GoButton                    matlab.ui.control.Button
        ModeofoperationButtonGroup  matlab.ui.container.ButtonGroup
        AutomaticButton             matlab.ui.control.RadioButton
        ManualButton                matlab.ui.control.RadioButton
        TurnedoffButton             matlab.ui.control.RadioButton
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        SimulinkModelName % Description
        StartValue
        TargetValue
        cam
        turnfirst
    end
    
    methods (Access = private)
        
        % function results = updateVelocity(app, new_velocity)
        %     if isnan(new_velocity)
        %         uialert(app.UIFigure, 'Must be a num', ...
        %             'Error', 'Icon', 'Info');
        %     end
        %     set_param([app.SimulinkModelName '/motor_speed'], 'Value', mat2str(new_velocity));
        %     app.BrzinaMotoraSlider.Value = new_velocity;
        %     app.BrzinaMotoraEditField.Value = new_velocity;
        % end
        
        function results = updateBazaDegrees(app, new_degree)
            set_param([app.SimulinkModelName '/Constant1'], 'Value', mat2str(new_degree));
            app.baza_slider.Value = new_degree;
        end

        function results = updateSrednjiZglobDegrees(app, new_degree)
            set_param([app.SimulinkModelName '/Constant2'], 'Value', mat2str(new_degree));
            app.srednjizglob_slider.Value = new_degree;
        end

        function results = updateZadnjiZglobDegrees(app, new_degree)
            set_param([app.SimulinkModelName '/Constant3'], 'Value', mat2str(new_degree));
            app.zadnjizglob_slider.Value = new_degree;
        end

        function performYOLO(app, startValue, targetValue)
            camera_info = imaqhwinfo('winvideo');
            device_id = camera_info.DeviceInfo(2).DeviceID;
            default_format = camera_info.DeviceInfo(2).DefaultFormat;
            supported_formats = camera_info.DeviceInfo(2).SupportedFormats;
            app.cam = 'Ukljuceno'
            imaqreset
            video_obj = videoinput('winvideo', device_id, default_format);
            video_obj.ReturnedColorSpace = 'rgb';
            f = figure('Visible', 'off'); 
            vidRes = video_obj.VideoResolution;
            imageRes = fliplr(vidRes);
            hImage = imshow(zeros(imageRes));
            % preview video object
            preview(video_obj, hImage);
            waitforbuttonpress;
            stoppreview(video_obj);
            % start to enable grab
            start(video_obj);
            img = getdata(video_obj);
            img = img(:, :, (1:3));
            imwrite(img, "img1_path.png")
            stop(video_obj);
            clear video_obj
            img = imread('img1_path.png');
            disp(['Start: ', lower(app.StartValue)]);
            disp(['Target: ', lower(app.TargetValue)]);
            yolo = yolov4ObjectDetector('csp-darknet53-coco');
            [bboxes, scores, labels] = detect(yolo, img);

            obstacleBoxes = bboxes(labels~=lower(app.StartValue) & labels~=lower(app.TargetValue), :);
            startBox = bboxes(labels==lower(app.StartValue), :);
            targetBox = bboxes(labels==lower(app.TargetValue), :);
            
            % Define the environment
            [height, width, ~] = size(img);
            
            % Start and goal positions (defined as the center of the bounding boxes)
            startPos = [startBox(1) + startBox(3)/2, startBox(2) + startBox(4)/2];
            goalPos = [targetBox(1) + targetBox(3)/2, targetBox(2) + targetBox(4)/2];
            
            % Obstacles are defined as bounding boxes (x, y, width, height)
            obstacles = obstacleBoxes;


            % Create a new figure for the grid network
            figure;
            imshow(img);
            hold on;
            
            % Plot the start and target boxes
            rectangle('Position', startBox, 'EdgeColor', 'blue', 'LineWidth', 2);
            rectangle('Position', targetBox, 'EdgeColor', 'red', 'LineWidth', 2);
            
            % Plot the obstacle boxes
            for i = 1:size(obstacleBoxes, 1)
                rectangle('Position', obstacleBoxes(i, :), 'EdgeColor', 'yellow', 'LineWidth', 2);
            end
            
            function path = rrt(startPos, goalBox, obstacles, width, height, max_iter, step_size)
            nodes = startPos; % Start node
            parents = 0; % Parent nodes
            goalReached = false;
        
            for i = 1:max_iter
                % Sample a random point
                randPoint = [rand * width, rand * height];
        
                % Find the nearest node
                dists = sqrt(sum((nodes - randPoint).^2, 2));
                [~, nearestIdx] = min(dists);
                nearestNode = nodes(nearestIdx, :);
        
                % Generate a new point in the direction of the random point
                theta = atan2(randPoint(2) - nearestNode(2), randPoint(1) - nearestNode(1));
                newPoint = nearestNode + step_size * [cos(theta), sin(theta)];
        
                % Check if the new point is in the obstacle
                if ~inObstacle(newPoint, obstacles)
                    % Add the new point to the tree
                    nodes = [nodes; newPoint];
                    parents = [parents; nearestIdx];
        
                    % Plot the new edge
                    plot([nearestNode(1), newPoint(1)], [nearestNode(2), newPoint(2)], 'b');
        
                    % Check if the goal is reached (within the goal bounding box)
                    if inGoal(newPoint, goalBox)
                        goalReached = true;
                        break;
                    end
                end
            end
        
            % Extract the path
            if goalReached
                path = newPoint; % Start path with the goal point
                currentNode = size(nodes, 1);
                while currentNode ~= 0
                    path = [nodes(currentNode, :); path];
                    currentNode = parents(currentNode);
                end
            else
                path = [];
            end
        end
        
        function isInObstacle = inObstacle(point, obstacles)
            isInObstacle = false;
            for i = 1:size(obstacles, 1)
                if point(1) >= obstacles(i, 1) && point(1) <= (obstacles(i, 1) + obstacles(i, 3)) && ...
                   point(2) >= obstacles(i, 2) && point(2) <= (obstacles(i, 2) + obstacles(i, 4))
                    isInObstacle = true;
                    break;
                end
            end
        end
        
        function isInGoal = inGoal(point, goalBox)
            isInGoal = point(1) >= goalBox(1) && point(1) <= (goalBox(1) + goalBox(3)) && ...
                       point(2) >= goalBox(2) && point(2) <= (goalBox(2) + goalBox(4));
        end
        
        % Parameters
        max_iter = 1000; % Maximum number of iterations
        step_size = 10; % Step size for each iteration
        
        % Define the start position
        startPos = [startBox(1) + startBox(3)/2, startBox(2) + startBox(4)/2];
        
        % Run RRT
        path = rrt(startPos, targetBox, obstacleBoxes, width, height, max_iter, step_size);
        
        % Plot the final path
        if ~isempty(path)
            plot(path(:, 1), path(:, 2), 'r', 'LineWidth', 2);
            path_50 = app.interpolatePath(path, 30);
            % path_100 = interpolatePath(path, 100);
            % path_200 = interpolatePath(path, 200);
            KOORDINATE_TOCKE = path_50;

            
            K = [
                1198.4, 0, 539.9;
                0, 1195.2, 342.7;
                0, 0, 1.0
            ];
            
            % Pohrana lokacije
            location_from_world = [12.0319, 227.2648, 600.0371];
            
            % Pohrana orijentacije
            orientation_from_world = [
                0.9994, -0.0206, 0.0277;
               -0.0218, -0.9988, 0.0430;
                0.0268, -0.0436, -0.9987
            ];

            
            location_from_pattern = [87.0121, 89.5162, -600.0371];
            
            
            % ------------------------------------------------------
            % iznad linije uvijek pokrecete, to je kalibracija sa slikom img1.png
            % tj. slika checkboarda na podlozi, ako se pozicija kamere promijenila
            % a vjv je jer ju svako malo neko nastimava,
            % treba s kamere uslikat novu sliku kao i img1.png (dakle sa checkboardom polozenim na plocu)
            
            % dvije opcije, odabrat onu koja daje bolje rezultate koordinata
            
            
            % PRVA
            % umjesto KOORDINATE_TOCKE saljete [x, y] u pikselima iz slike
            % dakle x,y putanje
            %-----------------------------------------
            lista_tocaka = KOORDINATE_TOCKE;
            BR_TOCAKA = size(lista_tocaka, 1);
            
            % cx, cy, fx, fy are instrinsic camera parameters, extract them from the
            % matrix
            cx = K(1, 3);
            cy = K(2, 3);
            fx = K(1, 1);
            fy = K(2, 2);
            
            % calculate centroid in world coordinate frame
            T_world_camera = [orientation_from_world', location_from_world'; 0 0 0 1];
            
            for i = 1:BR_TOCAKA
                centroid = lista_tocaka(i,:);
                % z_pattern = % distance of the pattern from the camera
                z_pattern = location_from_pattern(3); % MOZDA TREBA location_from_world(1)
                % nisam sig
                  
                % u = % centroid x value in pixels
                u = centroid(1);
                % v = % centroid y value in pixels
                v = centroid(2);
            
                % insert calculation from the lecture slides
                x_camera = [ (z_pattern*(u - cx)) / fx ];
                y_camera = [ (z_pattern*(v - cy)) / fy ];
                z_camera = [ z_pattern ];
               
                % convert to homogeneous coordinates
                centroid_camera_h = [x_camera; y_camera; z_camera; 1];
                
                centroid_world_h = T_world_camera * centroid_camera_h;
                
                % display position of the object in frame {R}
                centroid_world = centroid_world_h(1:3,1);
                % msm da bi u ^ovoj varijabli trebo bit rezultat
            
                centroid_world_list(i,:) = centroid_world;
            end
            
            RJESENJE = centroid_world_list;
            
            % % DRUGA
            % %------------------------------------------------------
            % % extract the centroid of the colored circle 
            % centroid = KOORDINATE_TOCKE;
            % 
            % % u = % centroid x value in pixels
            % % v = % centroid y value in pixels
            % u = centroid(1);
            % v = centroid(2);
            % 
            % % display position of the object in frame {R}
            % rezultat = pointsToWorld(cameraParams.Intrinsics, R, t, [u v]);
            % % u ^ovoj varijabli bi trebo bit rezultat
            
            
            %---------------------------------------------------
            % preporucam da opciju koju odaberete stavite u for petlju i izvrtite
            % za svih 20, 50, 100 il kolko vec tocaka putanje, i onda to spremite u
            % neku listu [x,y,z] koordinata (z bi trebo bit uvijek isti) koje onda
            % redom saljete na racunanje inverzne kinematike, pa postavljate
            % vrijednosti zglobova na dobivene rezultate
            
            % na taj nacin se onda ovaj potprogram moze samo jednom pokrenut nakon
            % izvrsavanja yolo detekcije, ne treba konstantno za svaku tocku kao npr
            % kad se svaki put mijenja inverzna il direktna kin
                        
            tocke = RJESENJE;
            length = size(tocke, 1);
            for i = length:-1:1
                tocka = tocke(i,:);
                iks = double(tocka(1));
                ips = double(tocka(2));
                app.XpositionEditField.Value = iks;
                app.YpositionEditField.Value = ips;
                app.ZpositionEditField.Value = 0;
                if i==1
                    app.turnfirst = true;
                end
                app.GoButton_2Pushed();
                disp(['TOCKE: ', num2str(tocka)]);
                pause(1);

                
            end
    
        else
            disp('Path not found');
        end
        
        hold off;
        title('Grid Network with Start, Target, and Obstacles');


        end

        % Function to interpolate the path
        function interpolatedPath = interpolatePath(app, path, num_points)
            % Create a linear space for the interpolation
            t = linspace(0, 1, size(path, 1));
            t_interp = linspace(0, 1, num_points);
            
            % Interpolate x and y coordinates separately
            x_interp = interp1(t, path(:, 1), t_interp);
            y_interp = interp1(t, path(:, 2), t_interp);
            
            % Combine the interpolated coordinates into a single path
            interpolatedPath = [x_interp', y_interp'];
        end

        function performDirect(app, slider1, slider2, slider3)
            run('parametri.m');
            theta1_deg = slider1;
            theta2_deg = slider2;
            theta3_deg = slider3;

            run('direktna_kinematika3.m');

            app.ZpositionEditField.Value = posz;
            app.YpositionEditField.Value = posy;
            app.XpositionEditField.Value = posx;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulinkModelName = 'simulation_three_motors';
            app.StartValue = 'Cat';
            app.TargetValue = 'Cat';
            app.turnfirst = false;
            app.GoButton.Enable = false;
            app.GoButton_2.Enable = false;
            app.baza_slider.Enable = false;
            app.srednjizglob_slider.Enable = false;
            app.zadnjizglob_slider.Enable = false;
            app.EditField.Enable = false;
            app.EditField2.Enable = false;
            app.EditField3.Enable = false;
            app.ZpositionEditField.Enable = false;
            app.YpositionEditField.Enable = false;
            app.XpositionEditField.Enable = false;
            app.TargetDropDown.Enable = false;
            app.StartDropDown.Enable = false;
            run ('parametri.m');
            app.baza_slider.Value = theta1_deg;
            app.srednjizglob_slider.Value = theta2_deg;
            app.zadnjizglob_slider.Value = theta3_deg;
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
        end

        % Callback function
        function BrzinaMotoraSliderValueChanged(app, event)
            new_velocity = app.BrzinaMotoraSlider.Value;
      
            app.updateVelocity(new_velocity);
        end

        % Callback function
        function BrzinaMotoraEditFieldValueChanged(app, event)
            new_velocity = app.BrzinaMotoraEditField.Value;
            
            app.updateVelocity(new_velocity);
        end

        % Selection changed function: ModeofoperationButtonGroup
        function ModeofoperationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeofoperationButtonGroup.SelectedObject;
            if selectedButton == app.TurnedoffButton
                app.GoButton.Enable = false;
                app.GoButton_2.Enable = false;
                app.baza_slider.Enable = false;
                app.srednjizglob_slider.Enable = false;
                app.zadnjizglob_slider.Enable = false;
                app.EditField.Enable = false;
                app.EditField2.Enable = false;
                app.EditField3.Enable = false;
                app.ZpositionEditField.Enable = false;
                app.YpositionEditField.Enable = false;
                app.XpositionEditField.Enable = false;
                app.TargetDropDown.Enable = false;
                app.StartDropDown.Enable = false;
            elseif selectedButton == app.ManualButton
                app.GoButton.Enable = false;
                app.GoButton_2.Enable = false;
                app.baza_slider.Enable = true;
                app.srednjizglob_slider.Enable = true;
                app.zadnjizglob_slider.Enable = true;
                app.EditField.Enable = true;
                app.EditField2.Enable = true;
                app.EditField3.Enable = true;
                app.ZpositionEditField.Enable = false;
                app.YpositionEditField.Enable = false;
                app.XpositionEditField.Enable = false;
                app.TargetDropDown.Enable = false;
                app.StartDropDown.Enable = false;
            elseif selectedButton == app.AutomaticButton
                app.GoButton.Enable = true;
                app.GoButton_2.Enable = true;
                app.baza_slider.Enable = false;
                app.srednjizglob_slider.Enable = false;
                app.zadnjizglob_slider.Enable = false;
                app.EditField.Enable = false;
                app.EditField2.Enable = false;
                app.EditField3.Enable = false;
                app.ZpositionEditField.Enable = true;
                app.YpositionEditField.Enable = true;
                app.XpositionEditField.Enable = true;
                app.TargetDropDown.Enable = true;
                app.StartDropDown.Enable = true;
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            performYOLO(app, app.StartValue, app.TargetValue);

        end

        % Value changed function: baza_slider
        function baza_sliderValueChanged(app, event)
            value = app.baza_slider.Value;
            app.EditField.Value = value;
            app.updateBazaDegrees(value);
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
            % kad se ovo promini prominit i x y z
        end

        % Value changed function: srednjizglob_slider
        function srednjizglob_sliderValueChanged(app, event)
            value = app.srednjizglob_slider.Value;
            app.EditField3.Value = value;
            app.updateSrednjiZglobDegrees(value);
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
            % kad se ovo promini prominit i x y z
        end

        % Value changed function: zadnjizglob_slider
        function zadnjizglob_sliderValueChanged(app, event)
            value = app.zadnjizglob_slider.Value;
            app.EditField2.Value = value;
            app.updateZadnjiZglobDegrees(value);
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
            % kad se ovo promini prominit i x y z
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.baza_slider.Value = value;
            app.updateBazaDegrees(value);
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
            % kad se ovo promini prominit i x y z
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            app.zadnjizglob_slider.Value = value;
            app.updateZadnjiZglobDegrees(value);
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
            % kad se ovo promini prominit i x y z
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            value = app.EditField3.Value;
            app.srednjizglob_slider.Value = value;
            app.updateSrednjiZglobDegrees(value);
            app.performDirect(app.baza_slider.Value, app.srednjizglob_slider.Value, app.zadnjizglob_slider.Value);
            % kad se ovo promini prominit i x y z
        end

        % Value changed function: StartDropDown
        function StartDropDownValueChanged(app, event)
            app.StartValue = app.StartDropDown.Value;
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            app.TargetValue = app.TargetDropDown.Value;
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
            % posz = app.ZpositionEditField;
            % posy = app.YpositionEditField;
            % posx = app.XpositionEditField;
            run('parametri.m');
            targetPos = [app.XpositionEditField.Value, app.YpositionEditField.Value, app.ZpositionEditField.Value-0.5];
            
            run('inverzna_kinematika2.m');
            disp(configSol);
            app.zadnjizglob_slider.Value = configSol(3);
            app.EditField2.Value = configSol(3);
            app.updateZadnjiZglobDegrees(configSol(3));
            if app.turnfirst
                pause(2.5);
                app.turnfirst = false;
            end
            app.baza_slider.Value = configSol(1);
            app.EditField.Value = configSol(1);
            app.updateBazaDegrees(configSol(1));
            app.srednjizglob_slider.Value = configSol(2);
            app.EditField3.Value = configSol(2);
            app.updateSrednjiZglobDegrees(configSol(2));
            
        end

        % Value changed function: XpositionEditField
        function XpositionEditFieldValueChanged(app, event)
            value = app.XpositionEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Background.png');
            app.ModeofoperationButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeofoperationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeofoperationButtonGroupSelectionChanged, true);
            app.ModeofoperationButtonGroup.Title = 'Mode of operation';
            app.TurnedoffButton = uiradiobutton(app.ModeofoperationButtonGroup);
            app.TurnedoffButton.Text = 'Turned off';
            app.TurnedoffButton.Value = true;
            app.ManualButton = uiradiobutton(app.ModeofoperationButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.AutomaticButton = uiradiobutton(app.ModeofoperationButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ElbowLabel_2 = uilabel(app.UIFigure);
            app.ElbowLabel_2.Text = 'Elbow';
            app.WristLabel = uilabel(app.UIFigure);
            app.WristLabel.Text = 'Wrist';
            app.zadnjizglob_slider = uislider(app.UIFigure);
            app.zadnjizglob_slider.Limits = [0 150];
            app.zadnjizglob_slider.ValueChangedFcn = createCallbackFcn(app, @zadnjizglob_sliderValueChanged, true);
            app.srednjizglob_slider = uislider(app.UIFigure);
            app.srednjizglob_slider.Limits = [-90 0];
            app.srednjizglob_slider.ValueChangedFcn = createCallbackFcn(app, @srednjizglob_sliderValueChanged, true);
            app.baza_slider = uislider(app.UIFigure);
            app.baza_slider.Limits = [-150 150];
            app.baza_slider.ValueChangedFcn = createCallbackFcn(app, @baza_sliderValueChanged, true);
            app.ShoulderLabel_2 = uilabel(app.UIFigure);
            app.ShoulderLabel_2.Text = 'Shoulder';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [-150 150];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Limits = [0 150];
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Limits = [-90 0];
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.StartDropDownLabel = uilabel(app.UIFigure);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.UIFigure);
            app.StartDropDown.Items = {'Cat', 'Car', 'Clock', 'Dog', 'Stop Sign', 'Traffic Light'};
            app.StartDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.StartDropDown.Placeholder = 'Start';
            app.StartDropDown.Value = 'Cat';
            app.TargetDropDownLabel = uilabel(app.UIFigure);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.UIFigure);
            app.TargetDropDown.Items = {'Cat', 'Car', 'Clock', 'Dog', 'Stop Sign', 'Traffic Light'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = 'Cat';
            app.GoButton_2 = uibutton(app.UIFigure, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.XpositionEditFieldLabel = uilabel(app.UIFigure);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XpositionEditField.ValueChangedFcn = createCallbackFcn(app, @XpositionEditFieldValueChanged, true);
            app.YpositionEditFieldLabel = uilabel(app.UIFigure);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZpositionEditFieldLabel = uilabel(app.UIFigure);
            app.ZpositionEditFieldLabel.Text = 'Z position';
            app.ZpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoosecoordinatesforrobotpentargetLabel = uilabel(app.UIFigure);
            app.ChoosecoordinatesforrobotpentargetLabel.Text = {'Choose coordinates '; 'for robot pen target'};
            app.ChooseStartandTargetforrobotpentaskLabel = uilabel(app.UIFigure);
            app.ChooseStartandTargetforrobotpentaskLabel.Text = {'Choose ''Start'' and ''Target'' '; 'for robot pen task'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_control_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end